classdef Main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        TrackParametersTab    matlab.ui.container.Tab
        TrackImport           matlab.ui.control.Button
        ImportTrackFIleLabel  matlab.ui.control.Label
        CarParametersTab_2    matlab.ui.container.Tab
        CarImport             matlab.ui.control.Button
        ImportCarFIleLabel    matlab.ui.control.Label
        TyreParametersTab     matlab.ui.container.Tab
        TyreImport            matlab.ui.control.Button
        ImportTyreFIleLabel   matlab.ui.control.Label
        SimulationTab         matlab.ui.container.Tab
        SimulationLabel       matlab.ui.control.Label
        RunButton             matlab.ui.control.Button
        UITable               matlab.ui.control.Table
    end


    properties (Access = public)
        trackpath
        trackfile
        carpath
        carfile
        tyrepath
        tyrefile
        Output
        Results
        n = 1;
    end


    methods (Access = private)

        % Button pushed function: TrackImport
        function TrackImportPushed(app, event)
            [app.trackfile,app.trackpath] = uigetfile('*.mat');
        end

        % Button pushed function: CarImport
        function CarImportPushed(app, event)
            [app.carfile,app.carpath] = uigetfile('*.mat');
        end

        % Button pushed function: TyreImport
        function TyreImportPushed(app, event)
            [app.tyrefile,app.tyrepath] = uigetfile('*.mat');
        end

        % Button pushed function: RunButton
        function RunSim(app, event)
            clear Output Car Track Tyre
            %[app.Output] = main(app.trackpath,app.trackfile,app.carpath,app.carfile,app.tyrepath,app.tyrefile);
            %app.Results.time(app.n) = app.Output.laptime; 
            app.n = app.n+1;
            i = app.n;
            tabledata(i) = [i i+2];
            app.UITable.Data = tabledata;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrackParametersTab = uitab(app.TabGroup);
            app.TrackParametersTab.Title = 'Track Parameters';
            app.TrackImport = uibutton(app.TrackParametersTab, 'push');
            app.TrackImport.ButtonPushedFcn = createCallbackFcn(app, @TrackImportPushed, true);
            app.TrackImport.Text = 'Browse';
            app.ImportTrackFIleLabel = uilabel(app.TrackParametersTab);
            app.ImportTrackFIleLabel.Text = 'Import Track FIle';
            app.CarParametersTab_2 = uitab(app.TabGroup);
            app.CarParametersTab_2.Title = 'Car Parameters';
            app.CarImport = uibutton(app.CarParametersTab_2, 'push');
            app.CarImport.ButtonPushedFcn = createCallbackFcn(app, @CarImportPushed, true);
            app.CarImport.Text = 'Browse';
            app.ImportCarFIleLabel = uilabel(app.CarParametersTab_2);
            app.ImportCarFIleLabel.Text = 'Import Car FIle';
            app.TyreParametersTab = uitab(app.TabGroup);
            app.TyreParametersTab.Title = 'Tyre Parameters';
            app.TyreImport = uibutton(app.TyreParametersTab, 'push');
            app.TyreImport.ButtonPushedFcn = createCallbackFcn(app, @TyreImportPushed, true);
            app.TyreImport.Text = 'Browse';
            app.ImportTyreFIleLabel = uilabel(app.TyreParametersTab);
            app.ImportTyreFIleLabel.Text = 'Import Tyre FIle';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationLabel = uilabel(app.SimulationTab);
            app.SimulationLabel.Text = 'Simulation ';
            app.RunButton = uibutton(app.SimulationTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunSim, true);
            app.RunButton.Text = 'Run';
            app.UITable = uitable(app.SimulationTab);
            app.UITable.ColumnName = {'Simulation'; 'Lap Time'; 'Simulation Time'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Main

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end